classdef Final_Dice_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FirstRollButton         matlab.ui.control.Button
        FirstRollButton_2       matlab.ui.control.Button
        RollAgainButton         matlab.ui.control.Button
        RollAgainButton_2       matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        ExitButton              matlab.ui.control.Button
        ReturntoMainMenuButton  matlab.ui.control.Button
        Image1                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image3                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        Image6                  matlab.ui.control.Image
        Image5                  matlab.ui.control.Image
        SlideSwitchLabel        matlab.ui.control.Label
        SlideSwitch             matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FirstRollButton, FirstRollButton_2
        function FirstRollButtonPushed(app, event)

            roll = 0;
            
            while (roll < 1)232
                
                die1 = randi(6);
                die2 = randi(6);
                die3 = randi(6);
                die4 = randi(6);
                die5 = randi(6);
                die6 = randi(6);


                if die1 == 1
                    app.Image1.ImageSource = 'dice1.jpg';
                elseif die1 == 2
                    app.Image1.ImageSource = 'dice2.jpg';
                elseif die1 == 3
                    app.Image1.ImageSource = 'dice3.jpg';
                elseif die1 == 4
                    app.Image1.ImageSource = 'dice4.jpg';
                elseif die1 == 5
                    app.Image1.ImageSource = 'dice5.jpg';
                elseif die1 == 6
                    app.Image1.ImageSource = 'dice6.jpg';
                end
    
                if die2 == 1
                    app.Image2.ImageSource = 'dice1.jpg';
                elseif die2 == 2
                    app.Image2.ImageSource = 'dice2.jpg';
                elseif die2 == 3
                    app.Image2.ImageSource = 'dice3.jpg';
                 elseif die2 == 4
                    app.Image2.ImageSource = 'dice4.jpg';
                 elseif die2 == 5
                    app.Image2.ImageSource = 'dice5.jpg';
                 elseif die2 == 6
                    app.Image2.ImageSource = 'dice6.jpg';
                end
                    
                    
                if die3 == 1
                    app.Image3.ImageSource = 'dice1.jpg';
                 elseif die3 == 2
                    app.Image3.ImageSource = 'dice2.jpg';
                 elseif die3 == 3
                    app.Image3.ImageSource = 'dice3.jpg';
                 elseif die3 == 4
                    app.Image3.ImageSource = 'dice4.jpg';
                 elseif die3 == 5
                    app.Image3.ImageSource = 'dice5.jpg';
                 elseif die3 == 6
                    app.Image3.ImageSource = 'dice6.jpg';
                end


                if die4 == 1
                    app.Image4.ImageSource = 'dice1.jpg';
                 elseif die4 == 2
                    app.Image4.ImageSource = 'dice2.jpg';
                 elseif die4 == 3
                    app.Image4.ImageSource = 'dice3.jpg';
                elseif die4 == 4
                    app.Image4.ImageSource = 'dice4.jpg';
                 elseif die4 == 5
                    app.Image4.ImageSource = 'dice5.jpg';
                 elseif die4 == 6
                    app.Image4.ImageSource = 'dice6.jpg';
                end


                if die5 == 1
                    app.Image5.ImageSource = 'dice1.jpg';
                 elseif die5 == 2
                    app.Image5.ImageSource = 'dice2.jpg';
                 elseif die5 == 3
                    app.Image5.ImageSource = 'dice3.jpg';
                 elseif die5 == 4
                    app.Image5.ImageSource = 'dice4.jpg';
                 elseif die5 == 5
                    app.Image5.ImageSource = 'dice5.jpg';
                 elseif die5 == 6
                    app.Image5.ImageSource = 'dice6.jpg';
                end


                if die6 == 1
                    app.Image6.ImageSource = 'dice1.jpg';
                elseif die6 == 2
                    app.Image6.ImageSource = 'dice2.jpg';
                elseif die6 == 3
                    app.Image6.ImageSource = 'dice3.jpg';
                elseif die6 == 4
                    app.Image6.ImageSource = 'dice4.jpg';
                elseif die6 == 5
                    app.Image6.ImageSource = 'dice5.jpg';
                elseif die6 == 6
                    app.Image6.ImageSource = 'dice6.jpg';
                end
                
                
                roll = roll + 1
                
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Image1.ImageSource = 'blank.jpg';
            app.Image2.ImageSource = 'blank.jpg';
            app.Image3.ImageSource = 'blank.jpg';
            app.Image4.ImageSource = 'blank.jpg';
            app.Image6.ImageSource = 'blank.jpg';
            app.Image5.ImageSource = 'blank.jpg';
        end

        % Value changed function: SlideSwitch
        function SlideSwitchValueChanged(app, event)
            
            value = app.SlideSwitch.Value;
            while value == true
                die1 = 0          
            
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FirstRollButton = uibutton(app.UIFigure, 'push');
            app.FirstRollButton.ButtonPushedFcn = createCallbackFcn(app, @FirstRollButtonPushed, true);
            app.FirstRollButton.Text = 'First Roll';
            app.FirstRollButton_2 = uibutton(app.UIFigure, 'push');
            app.FirstRollButton_2.ButtonPushedFcn = createCallbackFcn(app, @FirstRollButtonPushed, true);
            app.FirstRollButton_2.Text = 'First Roll';
            app.RollAgainButton = uibutton(app.UIFigure, 'push');
            app.RollAgainButton.Text = 'Roll Again';
            app.RollAgainButton_2 = uibutton(app.UIFigure, 'push');
            app.RollAgainButton_2.Text = 'Roll Again';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
            app.ReturntoMainMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMainMenuButton.Text = {'Return to'; 'Main Menu'};
            app.Image1 = uiimage(app.UIFigure);
            app.Image1.ImageSource = 'blank.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'blank.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'blank.jpg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'blank.jpg';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'blank.jpg';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'blank.jpg';
            app.SlideSwitchLabel = uilabel(app.UIFigure);
            app.SlideSwitchLabel.Text = 'Slide';
            app.SlideSwitch = uiswitch(app.UIFigure, 'slider');
            app.SlideSwitch.ValueChangedFcn = createCallbackFcn(app, @SlideSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Dice_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end